classdef LMPM_8D_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CrationTrajectoireTab           matlab.ui.container.Tab
        UtilisateursavancsseulementLabel  matlab.ui.control.Label
        UploadTrajectorytemplateButton  matlab.ui.control.Button
        DownloadtemplatescripttrajectoryTemplatemButton  matlab.ui.control.Button
        valeurTpsTraj                   matlab.ui.control.Spinner
        DuredelatrajectoireensecondesLabel  matlab.ui.control.Label
        WaypointsLabel                  matlab.ui.control.Label
        Logo_LaboRobotique              matlab.ui.control.Image
        Console                         matlab.ui.control.TextArea
        ConsoleLabel                    matlab.ui.control.Label
        NbCycle                         matlab.ui.control.Spinner
        NombredecycledelatrajectoireLabel  matlab.ui.control.Label
        Logo_Ulaval                     matlab.ui.control.Image
        ExporterlaTrajectoireButton     matlab.ui.control.Button
        arrow2                          matlab.ui.control.Label
        ValidationTrajectoiresurlemodlephysiqueButton  matlab.ui.control.Button
        arrow                           matlab.ui.control.Label
        CrationTrajectoireetGestionRedondanceButton  matlab.ui.control.Button
        PositiondurobotpourledernierWaypointButton  matlab.ui.control.Button
        VisualiserlesWaypointsButton    matlab.ui.control.Button
        Remove                          matlab.ui.control.Button
        Add                             matlab.ui.control.Button
        WaypointsTable                  matlab.ui.control.Table
        GestionTrajectoireTab           matlab.ui.container.Tab
        OuvrirlemodleSimulinkdegestiondurobotButton  matlab.ui.control.Button
        ChangerlordredestrajectoiresButton  matlab.ui.control.Button
        SupprimerlatrajectoireslectionneButton  matlab.ui.control.Button
        LestrajectoiressontdanslordredexecutionsdelademodurobotLabel  matlab.ui.control.Label
        SuppressionTrajectoire          matlab.ui.control.DropDown
        ChangementTrajectoire_2         matlab.ui.control.DropDown
        ChangementTrajectoire           matlab.ui.control.DropDown
        ChangementTraj_Label_2          matlab.ui.control.Label
        ChangementTraj_Label            matlab.ui.control.Label
        SupprimerTraj_Label             matlab.ui.control.Label
        Console_2                       matlab.ui.control.TextArea
        ConsoleLabel_2                  matlab.ui.control.Label
        TrajectoireTable                matlab.ui.control.Table
        Logo_LaboRobotique_2            matlab.ui.control.Image
        Logo_Ulaval_2                   matlab.ui.control.Image
    end

    properties (Access = private)
        id = linspace(1,10,10);
        waypoint = 0;
        X
        Y
        Z
        phi
        theta
        validation = 0; %Verif si les conditions sont respectés pour créer une trajectoire
        %rho_Command % Matrice de commande du robot
        import  
        fileTrajectoire % Nom du fichier trajectoire importé par le user
        locationScript % Emplacement du script template Trajectoire
    end


    methods (Access = private)

        function WaypointUpdate(app)
            data = app.WaypointsTable.Data; % Data est un matrice n*5
            for i = 1:app.waypoint
                app.X(i) = data{i,1};
                app.Y(i) = data{i,2};
                app.Z(i) = data{i,3};
                app.phi(i) = data{i,4};
                app.theta(i) = data{i,5};
            end
            return;
        end
        
        function cleanWaypoint(app)     %Clean les Waypoint lorsqu'on enlève le dernier point
            app.X = [];
            app.Y = [];
            app.Z = [];
            app.phi = [];
            app.theta = [];
            
        end
        
        function nbWaypoint(app)
            if app.waypoint>=2 || ~isempty(app.fileTrajectoire)%isempty(app.fileTrajectoire)
                app.Console.Value = '';
                app.validation = 1;
            else
                app.Console.Value = 'Warning : Il faut minimum 2 waypoints pour calculer une trajectoire ou uploader un script de calcul de trajectoire';
                app.validation = 0;
            end
        end
        
        
        function loadTraj(app)
            addpath("pos_8mot\");          
            try     % if error (like trajVar.mat empty) = GOTO catch statement
                app.import = load("trajVar.mat");
                %app.rho_Command = import.rho_CommandRobot;
            catch 
                return;
            end
            
            app.TrajectoireTable.Data = [];  %Clear la table des trajectoires avant de la mettre à jours
            for i = 1:length(app.import.tpsTrajTot)
                data = app.TrajectoireTable.Data;
                app.TrajectoireTable.Data = [data; {app.import.nomTrajTot{i},app.import.tpsTrajTot(:,i)}];
            end
            %Load des options de traj dans les menus déroulants
            app.SuppressionTrajectoire.Items = app.import.nomTrajTot(:);
            app.ChangementTrajectoire.Items = app.import.nomTrajTot(:);
            app.ChangementTrajectoire_2.Items = app.import.nomTrajTot(:);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tempsTraj = app.valeurTpsTraj.Value;
            assignin("base","tempsTraj",tempsTraj);     %Sauvegarde de la var tempsTraj dans le workspace pour la rendre dispo pour les scripts
            nbWaypoint(app);
            addpath("Functions\")   % Fonctions nécessaires
            loadTraj(app);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            if (app.waypoint < 10)
                app.waypoint = app.waypoint + 1;
                data = app.WaypointsTable.Data;     %Stock les valeurs de la table
                newData = [data; {0, 0, -0.58, 0, 0}];
                app.WaypointsTable.Data = newData;
                app.WaypointsTable.RowName=string(app.id);
                
                nbWaypoint(app)     %écrit dans la console
            end
        end

        % Button pushed function: Remove
        function RemoveButtonPushed(app, event)
            if (app.waypoint > 0)
                app.waypoint = app.waypoint - 1;
                nbWaypoint(app)     %écrit dans la console
            end
            data = app.WaypointsTable.Data;
            if ~isempty(data)
                data(end, :) = [];
                app.WaypointsTable.Data = data;
            end
        end

        % Button pushed function: VisualiserlesWaypointsButton
        function VisualiserlesWaypointsButtonPushed(app, event)
            close all;

            cleanWaypoint(app);
            WaypointUpdate(app);
            
            pTraj = [app.X;app.Y;app.Z];
            QmatTraj = [app.phi;app.theta];
            QmatTraj = cat(1,QmatTraj,zeros(1,app.waypoint));
            
            assignin("base","pTraj",pTraj)     
            assignin("base","QmatTraj",QmatTraj)
            waypoints = app.waypoint;
            assignin("base","waypoints",waypoints);
            if app.validation == 1
                animationRobot;
            end    
        end

        % Button pushed function: 
        % PositiondurobotpourledernierWaypointButton
        function PositiondurobotpourledernierWaypointButtonPushed(app, event)
            if app.waypoint >= 1
                cleanWaypoint(app);
                WaypointUpdate(app);
                p = [app.X(app.waypoint);app.Y(app.waypoint);app.Z(app.waypoint)];
                Qmat = [app.phi(app.waypoint);app.theta(app.waypoint);0];
                assignin("base","p",p);     %Rendre accessible l'input de l'utilisateur pour le script robotPos.m
                assignin("base","Qmat",Qmat);
                robotPos;
            end
        end

        % Button pushed function: 
        % CrationTrajectoireetGestionRedondanceButton
        function CrationTrajectoireetGestionRedondanceButtonPushed(app, event)
            cleanWaypoint(app);
            WaypointUpdate(app)
            wayTraj = [app.X;app.Y;app.Z];          
            QmatTraj = [app.phi;app.theta;zeros(1,app.waypoint)];      
            
            assignin("base","wayTraj",wayTraj)     
            assignin("base","QmatTraj",QmatTraj)
            waypoints = app.waypoint;
            assignin("base","waypoints",waypoints);
            nbCyclesTraj = app.NbCycle.Value;
            assignin("base","nbCyclesTraj",nbCyclesTraj);
            tempsTraj = app.valeurTpsTraj.Value;
            assignin("base","tempsTraj",tempsTraj);
            fileTraj = app.fileTrajectoire;
            assignin("base","fileTraj",fileTraj);
            locTraj = app.locationScript;
            assignin("base","locTraj",locTraj);
            if app.validation == 1
                try
                    GestionRedondance;
                catch ME
                    app.Console.Value = 'Une erreur est survenue lors de la gestion de la redondance. Modifiez certains paramètres et retentez la gestion de la redondance. Message de l erreur :';
                    app.Console.Value = append(app.Console.Value,newline,ME.message);
                    return;
                end
            end
            app.fileTrajectoire = [];   %Après exe de gestionRedondance, fileTraj empty pour reset
            nbWaypoint(app) %Mise à jours de la console
        end

        % Cell edit callback: WaypointsTable
        function WaypointsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            param = indices(1,2);   %Permet de récupérer l'informations de la colonne (donc du param) qui est modifié
            
            XYMax = 0.15;
            XYMin = -0.15;
            ZMax = -0.40;
            ZMin = -0.7;
            angleMax = 90;
            angleMin = -90;
            
            switch param
                case 1  %Param X
                    if newData > XYMax
                        app.WaypointsTable.Data{indices(1,1),1} = XYMax;
                    elseif newData < XYMin
                        app.WaypointsTable.Data{indices(1,1),1} = XYMin;
                    end
                case 2   %Param Y
                    if newData > XYMax
                        app.WaypointsTable.Data{indices(1,1),2} = XYMax;
                    elseif newData < XYMin
                        app.WaypointsTable.Data{indices(1,1),2} = XYMin;
                    end
                case 3   %Param Z
                    if newData > ZMax
                        app.WaypointsTable.Data{indices(1,1),3} = ZMax;
                    elseif newData < ZMin
                        app.WaypointsTable.Data{indices(1,1),3} = ZMin;
                    end
                case 4   %Param φ
                    if newData > angleMax
                        app.WaypointsTable.Data{indices(1,1),4} = angleMax;
                    elseif newData < angleMin
                        app.WaypointsTable.Data{indices(1,1),4} = angleMin;
                    end
                case 5   %Param θ
                    if newData > angleMax
                        app.WaypointsTable.Data{indices(1,1),5} = angleMax;
                    elseif newData < angleMin
                        app.WaypointsTable.Data{indices(1,1),5} = angleMin;
                    end
            end
            
            
        end

        % Button pushed function: 
        % ValidationTrajectoiresurlemodlephysiqueButton
        function ValidationTrajectoiresurlemodlephysiqueButtonPushed(app, event)
            if app.validation == 1
                app.Console.Value = '';
                try
                    runValidateModel;
                catch ME
                    app.Console.Value = 'Une erreur est survenue lors de la simulation. Modifiez certains paramètres et retentez la gestion de la redondance.';
                    app.Console.Value = append(app.Console.Value,newline,'Message de l erreur :',newline,ME.message);
                    return;
                end
            end
            
            
        end

        % Button pushed function: ExporterlaTrajectoireButton
        function ExporterlaTrajectoireButtonPushed(app, event)
            tempsTraj = app.valeurTpsTraj.Value;
            assignin("base","tempsTraj",tempsTraj);
            if app.validation == 1
                try
                    exportTraj;
                catch ME
                    app.Console.Value = 'Une erreur est survenue lors de l exportation de la trajectoire';
                    app.Console.Value = append(app.Console.Value,newline,'Message de l erreur :',newline,ME.message);
                    return;
                end
            end
            loadTraj(app);
        end

        % Button pushed function: 
        % SupprimerlatrajectoireslectionneButton
        function SupprimerlatrajectoireslectionneButtonPushed(app, event)
            if ~isempty(app.SuppressionTrajectoire.Value)
                index = find(strcmp(app.import.nomTrajTot,app.SuppressionTrajectoire.Value));
                app.import.nomTrajTot(index) = [];
                app.import.tpsTrajTot(index) = [];
                app.import.cmdRobot(index) = [];
                
                %Changement de var pour les sauvegarder dans trajVar.mat
                tpsTrajTot = app.import.tpsTrajTot;
                nomTrajTot = app.import.nomTrajTot;
                cmdRobot = app.import.cmdRobot;
                
                save("pos_8mot\trajVar.mat","tpsTrajTot","nomTrajTot","cmdRobot");
                loadTraj(app); %Mise à jours de la fenetre de gestion des trajectoires
            end
        end

        % Button pushed function: ChangerlordredestrajectoiresButton
        function ChangerlordredestrajectoiresButtonPushed(app, event)
            if ~isempty(app.ChangementTrajectoire.Value)
                index1 = find(strcmp(app.import.nomTrajTot,app.ChangementTrajectoire.Value));
                index2 = find(strcmp(app.import.nomTrajTot,app.ChangementTrajectoire_2.Value));
                
                temp = app.import.nomTrajTot{1,index2} ;
                app.import.nomTrajTot{1,index2} = app.import.nomTrajTot{1,index1};
                app.import.nomTrajTot{1,index1} = temp;
                
                temp = app.import.tpsTrajTot(:,:,index2);
                app.import.tpsTrajTot(:,:,index2) = app.import.tpsTrajTot(:,:,index1);
                app.import.tpsTrajTot(:,:,index1) = temp;
                
                temp = app.import.cmdRobot(index2);
                app.import.cmdRobot(index2) = app.import.cmdRobot(index1);
                app.import.cmdRobot(index1) = temp;


                tpsTrajTot = app.import.tpsTrajTot;
                nomTrajTot = app.import.nomTrajTot;
                cmdRobot = app.import.cmdRobot;
                
                save("pos_8mot\trajVar.mat","tpsTrajTot","nomTrajTot","cmdRobot");
                loadTraj(app); %Mise à jours de la fenetre de gestion des trajectoires
            end
        end

        % Button pushed function: 
        % DownloadtemplatescripttrajectoryTemplatemButton
        function DownloadtemplatescripttrajectoryTemplatemButtonPushed(app, event)
            [file,location] = uiputfile('trajectoryTemplate.m');
            if ~isequal(file,0)
               copyfile(file,location)     %Copie le fichier trajectoryTemplate se trouvant dans le répertoire de travail 
            end
        end

        % Button pushed function: UploadTrajectorytemplateButton
        function UploadTrajectorytemplateButtonPushed(app, event)
            [app.fileTrajectoire,app.locationScript] = uigetfile('*.m');
            nbWaypoint(app);
        end

        % Button pushed function: 
        % OuvrirlemodleSimulinkdegestiondurobotButton
        function OuvrirlemodleSimulinkdegestiondurobotButtonPushed(app, event)
            model = 'pos_8mot\pos_8mot';
            open_system(model);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CrationTrajectoireTab = uitab(app.TabGroup);
            app.CrationTrajectoireTab.Title = 'Création Trajectoire';
            app.WaypointsTable = uitable(app.CrationTrajectoireTab);
            app.WaypointsTable.ColumnName = {'X'; 'Y'; 'Z'; 'φ'; 'θ'};
            app.WaypointsTable.RowName = {'''numbered'''};
            app.WaypointsTable.ColumnEditable = true;
            app.WaypointsTable.CellEditCallback = createCallbackFcn(app, @WaypointsTableCellEdit, true);
            app.Add = uibutton(app.CrationTrajectoireTab, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.Remove = uibutton(app.CrationTrajectoireTab, 'push');
            app.Remove.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.Remove.Text = '-';
            app.VisualiserlesWaypointsButton = uibutton(app.CrationTrajectoireTab, 'push');
            app.VisualiserlesWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiserlesWaypointsButtonPushed, true);
            app.VisualiserlesWaypointsButton.Text = 'Visualiser les Waypoints';
            app.PositiondurobotpourledernierWaypointButton = uibutton(app.CrationTrajectoireTab, 'push');
            app.PositiondurobotpourledernierWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @PositiondurobotpourledernierWaypointButtonPushed, true);
            app.PositiondurobotpourledernierWaypointButton.Text = {'Position du robot pour le'; 'dernier Waypoint'};
            app.CrationTrajectoireetGestionRedondanceButton = uibutton(app.CrationTrajectoireTab, 'push');
            app.CrationTrajectoireetGestionRedondanceButton.ButtonPushedFcn = createCallbackFcn(app, @CrationTrajectoireetGestionRedondanceButtonPushed, true);
            app.CrationTrajectoireetGestionRedondanceButton.Text = {'Création Trajectoire'; 'et GestionRedondance'};
            app.arrow = uilabel(app.CrationTrajectoireTab);
            app.arrow.Text = '→';
            app.ValidationTrajectoiresurlemodlephysiqueButton = uibutton(app.CrationTrajectoireTab, 'push');
            app.ValidationTrajectoiresurlemodlephysiqueButton.ButtonPushedFcn = createCallbackFcn(app, @ValidationTrajectoiresurlemodlephysiqueButtonPushed, true);
            app.ValidationTrajectoiresurlemodlephysiqueButton.Text = {'Validation Trajectoire sur'; 'le modèle physique'};
            app.arrow2 = uilabel(app.CrationTrajectoireTab);
            app.arrow2.Text = '→';
            app.ExporterlaTrajectoireButton = uibutton(app.CrationTrajectoireTab, 'push');
            app.ExporterlaTrajectoireButton.ButtonPushedFcn = createCallbackFcn(app, @ExporterlaTrajectoireButtonPushed, true);
            app.ExporterlaTrajectoireButton.Text = {'Exporter la '; 'Trajectoire'};
            app.Logo_Ulaval = uiimage(app.CrationTrajectoireTab);
            app.Logo_Ulaval.ImageSource = 'Logo_Ulaval.png';
            app.NombredecycledelatrajectoireLabel = uilabel(app.CrationTrajectoireTab);
            app.NombredecycledelatrajectoireLabel.Text = 'Nombre de cycle de la trajectoire :';
            app.NbCycle = uispinner(app.CrationTrajectoireTab);
            app.NbCycle.Limits = [1 20];
            app.NbCycle.Value = 1;
            app.ConsoleLabel = uilabel(app.CrationTrajectoireTab);
            app.ConsoleLabel.Text = 'Console :';
            app.Console = uitextarea(app.CrationTrajectoireTab);
            app.Console.Editable = 'off';
            app.Logo_LaboRobotique = uiimage(app.CrationTrajectoireTab);
            app.Logo_LaboRobotique.ImageSource = 'Logo_LaboRobotique_Ulaval.png';
            app.WaypointsLabel = uilabel(app.CrationTrajectoireTab);
            app.WaypointsLabel.Text = 'Waypoints';
            app.DuredelatrajectoireensecondesLabel = uilabel(app.CrationTrajectoireTab);
            app.DuredelatrajectoireensecondesLabel.Text = 'Durée de la trajectoire (en secondes) :';
            app.valeurTpsTraj = uispinner(app.CrationTrajectoireTab);
            app.valeurTpsTraj.Limits = [10 1000];
            app.valeurTpsTraj.Value = 10;
            app.DownloadtemplatescripttrajectoryTemplatemButton = uibutton(app.CrationTrajectoireTab, 'push');
            app.DownloadtemplatescripttrajectoryTemplatemButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadtemplatescripttrajectoryTemplatemButtonPushed, true);
            app.DownloadtemplatescripttrajectoryTemplatemButton.Text = {'Download template script'; 'trajectoryTemplate.m'};
            app.UploadTrajectorytemplateButton = uibutton(app.CrationTrajectoireTab, 'push');
            app.UploadTrajectorytemplateButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTrajectorytemplateButtonPushed, true);
            app.UploadTrajectorytemplateButton.Text = {'Upload Trajectory '; 'template'};
            app.UtilisateursavancsseulementLabel = uilabel(app.CrationTrajectoireTab);
            app.UtilisateursavancsseulementLabel.Text = '!------------Utilisateurs avancés seulement ------------!';
            app.GestionTrajectoireTab = uitab(app.TabGroup);
            app.GestionTrajectoireTab.Title = 'Gestion Trajectoire';
            app.Logo_Ulaval_2 = uiimage(app.GestionTrajectoireTab);
            app.Logo_Ulaval_2.ImageSource = 'Logo_Ulaval.png';
            app.Logo_LaboRobotique_2 = uiimage(app.GestionTrajectoireTab);
            app.Logo_LaboRobotique_2.ImageSource = 'Logo_LaboRobotique_Ulaval.png';
            app.TrajectoireTable = uitable(app.GestionTrajectoireTab);
            app.TrajectoireTable.ColumnName = {'Nom Trajectoire'; 'Temps Trajectoire (en s)'};
            app.TrajectoireTable.RowName = {};
            app.ConsoleLabel_2 = uilabel(app.GestionTrajectoireTab);
            app.ConsoleLabel_2.Text = 'Console :';
            app.Console_2 = uitextarea(app.GestionTrajectoireTab);
            app.Console_2.Editable = 'off';
            app.SupprimerTraj_Label = uilabel(app.GestionTrajectoireTab);
            app.SupprimerTraj_Label.Text = '- Pour supprimer une trajectoire, insérer son Nom :';
            app.ChangementTraj_Label = uilabel(app.GestionTrajectoireTab);
            app.ChangementTraj_Label.Text = '- Changer l''ordre des trajectoires entre :';
            app.ChangementTraj_Label_2 = uilabel(app.GestionTrajectoireTab);
            app.ChangementTraj_Label_2.Text = 'et :';
            app.ChangementTrajectoire = uidropdown(app.GestionTrajectoireTab);
            app.ChangementTrajectoire_2 = uidropdown(app.GestionTrajectoireTab);
            app.SuppressionTrajectoire = uidropdown(app.GestionTrajectoireTab);
            app.LestrajectoiressontdanslordredexecutionsdelademodurobotLabel = uilabel(app.GestionTrajectoireTab);
            app.LestrajectoiressontdanslordredexecutionsdelademodurobotLabel.Text = {'- Les trajectoires sont dans l''ordre d''execution de la demo du robot (haut vers le bas)'; '- La trajectoire Gabarit vers Standby n''est pas prise en compte ici'; '- Si rien n''est affiché c''est que vous n''avez pas encore créé une trajectoire'};
            app.SupprimerlatrajectoireslectionneButton = uibutton(app.GestionTrajectoireTab, 'push');
            app.SupprimerlatrajectoireslectionneButton.ButtonPushedFcn = createCallbackFcn(app, @SupprimerlatrajectoireslectionneButtonPushed, true);
            app.SupprimerlatrajectoireslectionneButton.Text = {'Supprimer la trajectoire'; 'sélectionnée'};
            app.ChangerlordredestrajectoiresButton = uibutton(app.GestionTrajectoireTab, 'push');
            app.ChangerlordredestrajectoiresButton.ButtonPushedFcn = createCallbackFcn(app, @ChangerlordredestrajectoiresButtonPushed, true);
            app.ChangerlordredestrajectoiresButton.Text = {'Changer l''ordre des '; 'trajectoires'};
            app.OuvrirlemodleSimulinkdegestiondurobotButton = uibutton(app.GestionTrajectoireTab, 'push');
            app.OuvrirlemodleSimulinkdegestiondurobotButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirlemodleSimulinkdegestiondurobotButtonPushed, true);
            app.OuvrirlemodleSimulinkdegestiondurobotButton.Text = {'Ouvrir le modèle Simulink '; 'de gestion du robot'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LMPM_8D_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end